classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        columnasLabel                   matlab.ui.control.Label
        Semuestranlosprimeros10registrosylasprimerasLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        ArchivoTab                      matlab.ui.container.Tab
        GaurdarButton                   matlab.ui.control.Button
        CargararchivoButton             matlab.ui.control.Button
        PreprocesadoTab                 matlab.ui.container.Tab
        ValidacincruzadaButton          matlab.ui.control.Button
        AlgoritmodepreprocesadoButton   matlab.ui.control.Button
        ClasificacinTab                 matlab.ui.container.Tab
        ClasificacinButton              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        DatosactualizadosLabel          matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        DanielaMejaRivasLabel           matlab.ui.control.Label
        HugoGonzlezGonzlezLabel         matlab.ui.control.Label
        ProyectoCienciadelosDatosLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ResultadosTextArea              matlab.ui.control.TextArea
        ResultadosTextAreaLabel         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        datos % Description
        datos_sin_outliers
        averageAccuracy % Description
        %accuracyText matlab.ui.control.ResultadosTextArea
        accuracy % Description
        ResultadosText % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargararchivoButton
        function CargararchivoButtonPushed(app, event)
           
    % Crear una interfaz de usuario para seleccionar un archivo CSV
    [file, path] = uigetfile('*.csv', 'Seleccione un archivo CSV');

    % Comprobar si se seleccionó un archivo
    if isequal(file, 0)
        % No se seleccionó ningún archivo
        uialert(app.UIFigure, 'No se seleccionó ningún archivo.', 'Error', 'Icon', 'error');
    else
        % Crear la ruta completa del archivo seleccionado
        archivo_csv = fullfile(path, file);

        % Leer los datos del archivo CSV
        app.datos = readtable(archivo_csv); 

        % Mostrar los datos leídos
        disp('Datos del archivo CSV:');
        disp(app.datos);


        % Asignar los primeros registros de los datos a la tabla
        num_registros = 10; % Número de registros a mostrar
        app.UITable.Data = app.datos(1:num_registros, :);

    end

        end

        % Button pushed function: AlgoritmodepreprocesadoButton
        function AlgoritmodepreprocesadoButtonPushed(app, event)
            
      
    % Obtener los datos de la variable 'datos'
    app.datos = app.datos; 


                % Obtener los datos de la tabla 'datos'
matrizDatos = table2array(app.datos);

% Calcular las medias y desviaciones estándar de cada columna
medias = mean(matrizDatos);
desviaciones = std(matrizDatos);

% Normalizar los datos utilizando la normalización Z-score
datosNormalizados = (matrizDatos - medias) ./ desviaciones;

% Crear una nueva tabla con los datos normalizados
datosNormalizadosTabla = array2table(datosNormalizados, 'VariableNames', app.datos.Properties.VariableNames);

% Mostrar los datos normalizados
%disp(datosNormalizadosTabla);

% Aplicar rmoutliers a la tabla data
app.datos_sin_outliers = rmoutliers(datosNormalizadosTabla);

% Mostrar la tabla resultante sin outliers
%disp(dataSinOutliers);

    % Aplicar rmoutliers a los datos
 %%%%   app.datos_sin_outliers = rmoutliers(app.datos);

    % Actualizar la variable 'datos' en  App Designer con los datos sin outliers
    app.datos = app.datos_sin_outliers;

    
    app.UITable2.Data = app.datos_sin_outliers;


        end

        % Button pushed function: ValidacincruzadaButton
        function ValidacincruzadaButtonPushed(app, event)
matrizDatos = table2array(app.datos_sin_outliers);
            
            % Dividir los datos en características (X) y etiquetas (Y)
X = matrizDatos(:, 1:end-1); % Características (todas las columnas excepto la última)
Y = matrizDatos(:, end); % Etiquetas (última columna)

% Definir los parámetros de la validación cruzada
numFolds = 10; % Número de particiones para la validación cruzada

% Inicializar el vector para almacenar las precisiones de cada partición
accuracy = zeros(numFolds, 1);

% Realizar la validación cruzada
cv = cvpartition(size(X, 1), 'KFold', numFolds);

for i = 1:numFolds
    % Obtener los conjuntos de entrenamiento y prueba para esta partición
    trainIdx = training(cv, i);
    testIdx = test(cv, i);
    X_train = X(trainIdx, :);
    Y_train = Y(trainIdx, :);
    X_test = X(testIdx, :);
    Y_test = Y(testIdx, :);

    % Crear y entrenar la red neuronal
    hiddenLayerSize = 100; % Número de neuronas en la capa oculta
    net = fitnet(hiddenLayerSize); % Crear la red neuronal
    net = train(net, X_train', Y_train'); % Entrenar la red neuronal
    
    % Evaluar la red neuronal en los datos de prueba
    Y_pred = net(X_test'); % Predecir las etiquetas en el conjunto de prueba
    
    % Calcular la precisión de la red neuronal
    accuracy(i) = sum(Y_pred' == Y_test) / length(Y_test);
end

% Calcular la precisión promedio
app.averageAccuracy = mean(accuracy);

% Mostrar la precisión promedio
disp(['Precisión promedio de la red neuronal con validación cruzada: ' num2str(app.averageAccuracy)]);

%app.ResultadosTextArea.Value = num2str(app.averageAccuracy);

% Obtener las etiquetas predichas por la red neuronal
Y_pred = net(X_test');

% Convertir las etiquetas a valores numéricos (0 y 1)
Y_pred_numeric = round(Y_pred);
Y_test_numeric = round(Y_test);

% Crear la matriz de confusión
C = confusionmat(Y_test_numeric, Y_pred_numeric);

% Mostrar la matriz de confusión como números
%disp('Matriz de Confusión:');
%disp(C);


% Obtener las etiquetas predichas por la red neuronal
Y_pred = net(X_test');

% Convertir las etiquetas a valores numéricos (0 y 1)
Y_pred_numeric = round(Y_pred);
Y_test_numeric = round(Y_test);

% Crear la matriz de confusión
C = confusionmat(Y_test_numeric, Y_pred_numeric);

% Extraer los valores de TP, FP, TN y FN
TP = C(2, 2);
FP = C(1, 2);
TN = C(1, 1);
FN = C(2, 1);

% Mostrar los valores de TP, FP, TN y FN
disp('Matriz de Confusión:');
disp(['Verdaderos Positivos (TP): ' num2str(TP)]);
disp(['Falsos Positivos (FP): ' num2str(FP)]);
disp(['Verdaderos Negativos (TN): ' num2str(TN)]);
disp(['Falsos Negativos (FN): ' num2str(FN)]);


% Obtener el objeto TextArea usando el Tag asignado
app.ResultadosTextArea = app.ResultadosTextArea;

% Construir el texto a mostrar en el TextArea
ResultadosText = sprintf('Matriz de Confusión:\n');
ResultadosText = [ResultadosText sprintf('Verdaderos Positivos (TP): %d\n', TP)];
ResultadosText = [ResultadosText sprintf('Falsos Positivos (FP): %d\n', FP)];
ResultadosText = [ResultadosText sprintf('Verdaderos Negativos (TN): %d\n', TN)];
ResultadosText = [ResultadosText sprintf('Falsos Negativos (FN): %d\n', FN)];

% Asignar el texto al objeto TextArea
app.ResultadosTextArea.Value = ResultadosText;
        end

        % Value changed function: ResultadosTextArea
        function ResultadosTextAreaValueChanged(app, event)
%             %value = app.ResultadosTextArea.Value;
% 
%             % Obtener el valor de precisión (accuracy)
% accuracyValue = data.averageAccuracy; % Reemplaza este valor con tu propia precisión calculada
% 
% % Obtener el objeto UITextArea usando su Tag
% accuracyText = app.accuracyText;
% 
% % Convertir el valor de precisión a texto
% accuracyString = num2str(accuracyValue);
% 
% % Actualizar el contenido del campo de texto
% accuracyText.Value = accuracyString;


        end

        % Button pushed function: ClasificacinButton
        function ClasificacinButtonPushed(app, event)


            % Convertir la tabla en una matriz
matrizDatos = table2array(app.datos);

%matrizDatos = app.datos_sin_outliers;

% Separar los datos en características (X) y etiquetas (Y)
X = matrizDatos(:, 1:end-1); % Características (todas las columnas excepto la última)
Y = matrizDatos(:, end); % Etiquetas (última columna)
            
            
            % Dividir los datos en conjuntos de entrenamiento y prueba
porcentajeEntrenamiento = 0.8; % Porcentaje de datos para entrenamiento (80%)
indicesEntrenamiento = randperm(size(X, 1), round(porcentajeEntrenamiento * size(X, 1)));
indicesPrueba = setdiff(1:size(X, 1), indicesEntrenamiento);

X_train = X(indicesEntrenamiento, :); % Conjunto de entrenamiento (características)
Y_train = Y(indicesEntrenamiento, :); % Conjunto de entrenamiento (etiquetas)

X_test = X(indicesPrueba, :); % Conjunto de prueba (características)
Y_test = Y(indicesPrueba, :); % Conjunto de prueba (etiquetas)

% Crear y entrenar la red neuronal
hiddenLayerSize = 100; % Número de neuronas en la capa oculta
net = fitnet(hiddenLayerSize); % Crear la red neuronal
net = train(net, X_train', Y_train'); % Entrenar la red neuronal

% Evaluar la red neuronal en los datos de prueba
Y_pred = net(X_test'); % Predecir las etiquetas en el conjunto de prueba

% Calcular la precisión de la red neuronal
app.accuracy = sum(Y_pred' == Y_test) / length(Y_test);

% Mostrar la precisión en la pantalla
disp(['Precisión de la red neuronal: ' num2str(app.accuracy)]);

app.ResultadosTextArea.Value = num2str(app.accuracy);
        end

        % Callback function: not associated with a component
        function GuardarresultadosButtonPushed(app, event)
            % Obtener los datos de la tabla desde la propiedad 'Data' o desde la variable donde se almacenan los datos
datos = app.datos_sin_outliers.Data; % Reemplaza 'MyTable' con el nombre de tu tabla

% Abrir el diálogo de selección de archivo
[archivo, carpeta] = uiputfile('*.csv', 'Guardar datos como');

% Comprobar si el usuario seleccionó un archivo
if archivo ~= 0
    % Construir la ruta completa del archivo
    rutaArchivo = fullfile(carpeta, archivo);

    % Guardar los datos en el archivo CSV
    writetable(datos, rutaArchivo);

    % Mostrar un mensaje de confirmación
    mensaje = sprintf('Datos guardados en el archivo: %s', rutaArchivo);
    disp(mensaje); % Opcional: mostrar el mensaje en la ventana de comandos
end

        end

        % Button pushed function: GaurdarButton
        function GaurdarButtonPushed(app, event)
            
    % Obtener los datos completos
    datosCompletos = app.datos_sin_outliers; % Reemplaza esto con tus propios datos completos

    % Realizar la validación cruzada
    k = 10; % Número de particiones
    cv = cvpartition(height(datosCompletos), 'KFold', k);

    % Iterar sobre cada partición
    for i = 1:k
        % Obtener los índices de entrenamiento y prueba de la partición actual
        trainIndices = training(cv, i);
        testIndices = test(cv, i);

        % Obtener los datos de entrenamiento y prueba de la partición actual
        datosEntrenamiento = datosCompletos(trainIndices, :);
        datosPrueba = datosCompletos(testIndices, :);

        % Guardar los datos de entrenamiento y prueba en archivos separados
        archivoEntrenamiento = sprintf('datos_entrenamiento_%d.csv', i);
        archivoPrueba = sprintf('datos_prueba_%d.csv', i);
        writetable(datosEntrenamiento, archivoEntrenamiento);
        writetable(datosPrueba, archivoPrueba);
    end

    % Mostrar un mensaje de confirmación
    disp('Datos de validación cruzada guardados en archivos exitosamente.');


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {308, '1x', 228};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.CargararchivoButton = uibutton(app.ArchivoTab, 'push');
            app.CargararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivoButtonPushed, true);
            app.CargararchivoButton.Text = 'Cargar archivo';
            app.GaurdarButton = uibutton(app.ArchivoTab, 'push');
            app.GaurdarButton.ButtonPushedFcn = createCallbackFcn(app, @GaurdarButtonPushed, true);
            app.GaurdarButton.Text = 'Gaurdar';
            app.PreprocesadoTab = uitab(app.TabGroup);
            app.PreprocesadoTab.Title = 'Preprocesado';
            app.AlgoritmodepreprocesadoButton = uibutton(app.PreprocesadoTab, 'push');
            app.AlgoritmodepreprocesadoButton.ButtonPushedFcn = createCallbackFcn(app, @AlgoritmodepreprocesadoButtonPushed, true);
            app.AlgoritmodepreprocesadoButton.Text = 'Algoritmo de preprocesado';
            app.ValidacincruzadaButton = uibutton(app.PreprocesadoTab, 'push');
            app.ValidacincruzadaButton.ButtonPushedFcn = createCallbackFcn(app, @ValidacincruzadaButtonPushed, true);
            app.ValidacincruzadaButton.Text = 'Validación cruzada';
            app.ClasificacinTab = uitab(app.TabGroup);
            app.ClasificacinTab.Title = 'Clasificación';
            app.ClasificacinButton = uibutton(app.ClasificacinTab, 'push');
            app.ClasificacinButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificacinButtonPushed, true);
            app.ClasificacinButton.Text = 'Clasificación';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Columna 1'; 'Columna 2'; 'Columna 3'; 'Columna 4'; 'Columna 5'; 'Columna 6'; 'Columna 7'; 'Columna 8'; 'Columna 9'; 'Columna 10'};
            app.UITable.RowName = {};
            app.Semuestranlosprimeros10registrosylasprimerasLabel = uilabel(app.LeftPanel);
            app.Semuestranlosprimeros10registrosylasprimerasLabel.Text = 'Se muestran los primeros 10 registros y las primeras';
            app.columnasLabel = uilabel(app.LeftPanel);
            app.columnasLabel.Text = '10 columnas';
            app.CenterPanel = uipanel(app.GridLayout);
            app.ProyectoCienciadelosDatosLabel = uilabel(app.CenterPanel);
            app.ProyectoCienciadelosDatosLabel.Text = 'Proyecto Ciencia de los Datos';
            app.HugoGonzlezGonzlezLabel = uilabel(app.CenterPanel);
            app.HugoGonzlezGonzlezLabel.Text = 'Hugo González González';
            app.DanielaMejaRivasLabel = uilabel(app.CenterPanel);
            app.DanielaMejaRivasLabel.Text = 'Daniela Mejía Rivas';
            app.UITable2 = uitable(app.CenterPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.DatosactualizadosLabel = uilabel(app.CenterPanel);
            app.DatosactualizadosLabel.Text = 'Datos actualizados';
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultadosTextAreaLabel = uilabel(app.RightPanel);
            app.ResultadosTextAreaLabel.Text = 'Resultados';
            app.ResultadosTextArea = uitextarea(app.RightPanel);
            app.ResultadosTextArea.ValueChangedFcn = createCallbackFcn(app, @ResultadosTextAreaValueChanged, true);
            app.ResultadosTextArea.Tag = 'accuracyText';
            app.ResultadosTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end